classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        DataTab                    matlab.ui.container.Tab
        WegdeSubmitButton          matlab.ui.control.Button
        StrategySubmitButton       matlab.ui.control.Button
        ConstraintsSubmitButton    matlab.ui.control.Button
        StrategyEditFieldLabel     matlab.ui.control.Label
        StrategyEditField          matlab.ui.control.EditField
        WedgesEditFieldLabel       matlab.ui.control.Label
        WedgesEditField            matlab.ui.control.EditField
        ConstraintsEditFieldLabel  matlab.ui.control.Label
        ConstraintsEditField       matlab.ui.control.EditField
        UseDefaultButton           matlab.ui.control.Button
        NothingEnteredLampLabel    matlab.ui.control.Label
        StrategyLamp               matlab.ui.control.Lamp
        NothingEnteredLabel        matlab.ui.control.Label
        WedgesLamp                 matlab.ui.control.Lamp
        NothingEnteredLamp_3Label  matlab.ui.control.Label
        ConstraintsLamp            matlab.ui.control.Lamp
        GameTab                    matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
        AvailableStrategiesLabel   matlab.ui.control.Label
        StrategiesListBox          matlab.ui.control.ListBox
        StrategiesInUseListBox     matlab.ui.control.ListBox
        StrategiesinUseLabel       matlab.ui.control.Label
        DescriptionLabel           matlab.ui.control.Label
        DescriptionTextArea        matlab.ui.control.TextArea
        StartYearSliderLabel       matlab.ui.control.Label
        StartYearSlider            matlab.ui.control.Slider
        EndYearSliderLabel         matlab.ui.control.Label
        EndYearSlider              matlab.ui.control.Slider
        ClicktoremoveLabel         matlab.ui.control.Label
        ConstraintsTable           matlab.ui.control.Table
        DeleteStrategyButton       matlab.ui.control.Button
        AddStrategyButton          matlab.ui.control.Button
        UpdateGraphButton          matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        ConstraintsLabel           matlab.ui.control.Label
        StrategyStartSpinnerLabel  matlab.ui.control.Label
        StrategyStartSpinner       matlab.ui.control.Spinner
        GoalButtonGroup            matlab.ui.container.ButtonGroup
        G2017Button                matlab.ui.control.RadioButton
        G1980Button                matlab.ui.control.RadioButton
        G801980Button              matlab.ui.control.RadioButton
        TotalWedgesNeededLabel     matlab.ui.control.Label
        TotalWedgesTextArea        matlab.ui.control.TextArea
        ErrorTextArea              matlab.ui.control.TextArea
        ErrorLogLabel              matlab.ui.control.Label
    end


    properties (Access = private)
        
        wedgesData; %Struct to store Wedges data from text file
        constraintsData; %Struct to store Constraints data from text file
        strategyData; %Struct to store Strategy data from text file
        historicalData; %Struct to store Historical data from text file
        
        
        num_wedges; %number of wedges allowed according to the goal
        
        
    end
    

    methods (Access = private)


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %update and plot historical data
            app.historicalData = tdfread("historicaldata.txt");
            histplot = plot(app.UIAxes,app.historicalData.Year,app.historicalData.fossil_fuel_and_industry_GtC0x2Fyr);
            histplot.LineWidth = 2;
            app.UIAxes.XLim = [1959 2068];
            app.StartYearSlider.Value = app.UIAxes.XLim(1);
            app.EndYearSlider.Value = app.UIAxes.XLim(2);
            
            hold(app.UIAxes,'on');
            
            %plot business as usual
            E_0 = app.historicalData.fossil_fuel_and_industry_GtC0x2Fyr(end);
            fplot(app.UIAxes,@(x) E_0*2^((x-2017)/50),[2017 2068]);
            
            %number of wedges for 2017 line
            app.num_wedges = floor(E_0*2^((2068-2017)/50) - E_0);
            display(app.num_wedges)


        end

        % Button pushed function: StrategySubmitButton
        function StrategySubmitButtonFcn(app, event)

            try 
                %load the file into a global variable as a struct
                strategy_filepath = app.StrategyEditField.Value; 
                app.strategyData = readtable(strategy_filepath, ' ');
  
                
                %update UI to show loading was a success
                app.StrategyLamp.Color = 'green';
                app.NothingEnteredLampLabel.Text = 'File Loaded';
       

            catch 
                %update UI to show error in loading file
                app.StrategyLamp.Color = 'red';
                app.NothingEnteredLampLabel.Text = 'File not Found';
            end

        end

        % Button pushed function: ConstraintsSubmitButton
        function ConstraintsSubmitButtonFcn(app, event)
            try 
                %load the file into a global variable as a struct
                constraints_filepath = app.ConstraintsEditField.Value;
                app.constraintsData = readtable(constraints_filepath);
                
                app.ConstraintsTable.Data = app.constraintsData;


                %update UI to show loading was a success
                app.ConstraintsLamp.Color = 'green';
                app.NothingEnteredLamp_3Label.Text = 'File Loaded';


            catch 
                %update UI to show error in loading file
                app.ConstraintsLamp.Color = 'red';
                app.NothingEnteredLamp_3Label.Text = 'File not Found';
            end
        end

        % Button pushed function: WegdeSubmitButton
        function WegdeSubmitButtonFcn(app, event)
            try 
                %load the file into a global variable as a struct
                wedges_filepath = app.WedgesEditField.Value;
                app.wedgesData = readtable(wedges_filepath);   
                
                
                %update UI to show loading was a success
                app.WedgesLamp.Color = 'green';
                app.NothingEnteredLabel.Text = 'File Loaded';
    
                app.StrategiesListBox.Items =app.wedgesData.StrategyName;
    
                
            catch 
                %update UI to show error in loading file
                app.WedgesLamp.Color = 'red';
                app.NothingEnteredLabel.Text = 'File not Found';
            end
        end

        % Value changed function: StrategiesListBox
        function StrategiesListBoxValueChanged(app, event)
            value = app.StrategiesListBox.Value;
            strategy = value{1};
    
            %show description
            index_of_description = find(app.wedgesData.StrategyName == convertCharsToStrings(strategy),1);
            app.DescriptionTextArea.Value = app.wedgesData.Description{index_of_description};
            
        end

        % Value changed function: StartYearSlider
        function StartYearSliderValueChanged(app, event)
            value = app.StartYearSlider.Value;
            
            %check to see if value is valid and change graph limits
            if value >= app.UIAxes.XLim(2)
                app.StartYearSlider.Value = app.UIAxes.XLim(1);
            else
                app.UIAxes.XLim(1) = value;
            end


        end

        % Value changed function: EndYearSlider
        function EndYearSliderValueChanged(app, event)
            value = app.EndYearSlider.Value;
            
            %check to see if value is valid and change graph limits
            if value <= app.UIAxes.XLim(1)
                app.EndYearSlider.Value = app.UIAxes.XLim(2);
            else
                app.UIAxes.XLim(2) = value;
            end
             
        end

        % Button pushed function: DeleteStrategyButton
        function DeleteStrategyButtonPushed(app, event)
            
            try
                %remove strategy from being used
                value = app.StrategiesInUseListBox.Value;
                display(value)
                index_to_be_removed = find(app.StrategiesInUseListBox.Items == convertCharsToStrings(value),1);
                app.StrategiesInUseListBox.Items(index_to_be_removed) = [];
                
                %find the sector by strategy name using Wedges data
                sector = app.wedgesData.Sector(app.wedgesData.StrategyName == convertCharsToStrings(value));
                sector = sector{1};
               
                
                %find the number left using sector
                index_of_sector = find(app.constraintsData.Sector == convertCharsToStrings(sector),1);
                num_left = app.constraintsData.Constraints(index_of_sector);
                
                %update the constraints data
                app.constraintsData.Constraints(index_of_sector) = num_left+1;
                
                %update UI of constraints table
                app.ConstraintsTable.Data = app.constraintsData;
                
            catch 
                
            end


        end

        % Button pushed function: AddStrategyButton
        function AddStrategyButtonPushed(app, event)
            
            
            %check if the strategy can be used
            %check to see if we are allowed more of this sector
            try
                value = app.StrategiesListBox.Value;
                strategy = value{1};
                %find the sector by strategy name using Wedges data
                sector = app.wedgesData.Sector(app.wedgesData.StrategyName == convertCharsToStrings(value));
                sector = sector{1};
                
                %find the number left using sector
                index_of_sector = find(app.constraintsData.Sector == convertCharsToStrings(sector),1);
                num_left = app.constraintsData.Constraints(index_of_sector);
                
                %see if we can still add in this sector
                if num_left >0
                    app.StrategiesInUseListBox.Items{end+1} = strategy;
                    app.constraintsData.Constraints(index_of_sector) = num_left-1;
                    
                    %update UI of constraints table
                    app.ConstraintsTable.Data = app.constraintsData;
                    
                end
            catch 

            end
        end

        % Button pushed function: UpdateGraphButton
        function UpdateGraphButtonPushed(app, event)
            %plot wedges for 2017
            
            %check to see the minmum number of wedges have been selected
            
            
            %check to see if one strategy is more than majority
            wegdes_used = app.StrategiesInUseListBox.Items;
            len = length(wegdes_used);
            for i = 1:len
                times_used = sum(wegdes_used == convertCharsToStrings(wegdes_used{i}));
                if times_used > len/2
                    app.ErrorTextArea.Value = sprintf("You are using %s %d times. It has to be less than half the total wedges.",wegdes_used{i},times_used);
                    return
                end
            end


        end

        % Selection changed function: GoalButtonGroup
        function GoalButtonGroupSelectionChanged(app, event)
            selectedButton = app.GoalButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "Same as 1980"
                    
                case "80% of 1980"
                    
                case "Same as 2017"
                    display(1)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.WegdeSubmitButton = uibutton(app.DataTab, 'push');
            app.WegdeSubmitButton.ButtonPushedFcn = createCallbackFcn(app, @WegdeSubmitButtonFcn, true);
            app.WegdeSubmitButton.Text = 'Submit';
            app.StrategySubmitButton = uibutton(app.DataTab, 'push');
            app.StrategySubmitButton.ButtonPushedFcn = createCallbackFcn(app, @StrategySubmitButtonFcn, true);
            app.StrategySubmitButton.Text = 'Submit';
            app.ConstraintsSubmitButton = uibutton(app.DataTab, 'push');
            app.ConstraintsSubmitButton.ButtonPushedFcn = createCallbackFcn(app, @ConstraintsSubmitButtonFcn, true);
            app.ConstraintsSubmitButton.Text = 'Submit';
            app.StrategyEditFieldLabel = uilabel(app.DataTab);
            app.StrategyEditFieldLabel.Text = 'Strategy';
            app.StrategyEditField = uieditfield(app.DataTab, 'text');
            app.StrategyEditField.Value = 'strategies.txt';
            app.WedgesEditFieldLabel = uilabel(app.DataTab);
            app.WedgesEditFieldLabel.Text = 'Wedges';
            app.WedgesEditField = uieditfield(app.DataTab, 'text');
            app.WedgesEditField.Value = 'wedges.txt';
            app.ConstraintsEditFieldLabel = uilabel(app.DataTab);
            app.ConstraintsEditFieldLabel.Text = 'Constraints';
            app.ConstraintsEditField = uieditfield(app.DataTab, 'text');
            app.ConstraintsEditField.Value = 'constraints.txt';
            app.UseDefaultButton = uibutton(app.DataTab, 'push');
            app.UseDefaultButton.Text = 'Use Default';
            app.NothingEnteredLampLabel = uilabel(app.DataTab);
            app.NothingEnteredLampLabel.Text = 'Nothing Entered';
            app.StrategyLamp = uilamp(app.DataTab);
            app.NothingEnteredLabel = uilabel(app.DataTab);
            app.NothingEnteredLabel.Text = 'Nothing Entered';
            app.WedgesLamp = uilamp(app.DataTab);
            app.NothingEnteredLamp_3Label = uilabel(app.DataTab);
            app.NothingEnteredLamp_3Label.Text = 'Nothing Entered';
            app.ConstraintsLamp = uilamp(app.DataTab);
            app.GameTab = uitab(app.TabGroup);
            app.GameTab.Title = 'Game';
            app.UIAxes = uiaxes(app.GameTab);
            title(app.UIAxes, 'Projected Pollution')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'Billions of Tons of Carbon per Year')
            app.AvailableStrategiesLabel = uilabel(app.GameTab);
            app.AvailableStrategiesLabel.Text = 'Available Strategies';
            app.StrategiesListBox = uilistbox(app.GameTab);
            app.StrategiesListBox.Items = {};
            app.StrategiesListBox.Multiselect = 'on';
            app.StrategiesListBox.ValueChangedFcn = createCallbackFcn(app, @StrategiesListBoxValueChanged, true);
            app.StrategiesListBox.Value = {};
            app.StrategiesInUseListBox = uilistbox(app.GameTab);
            app.StrategiesInUseListBox.Items = {};
            app.StrategiesInUseListBox.Value = {};
            app.StrategiesinUseLabel = uilabel(app.GameTab);
            app.StrategiesinUseLabel.Text = 'Strategies in Use';
            app.DescriptionLabel = uilabel(app.GameTab);
            app.DescriptionLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.GameTab);
            app.DescriptionTextArea.Editable = 'off';
            app.StartYearSliderLabel = uilabel(app.GameTab);
            app.StartYearSliderLabel.Text = 'Start Year';
            app.StartYearSlider = uislider(app.GameTab);
            app.StartYearSlider.Limits = [1959 2068];
            app.StartYearSlider.MajorTicks = [1959 1977 1995 2013 2031 2049 2068];
            app.StartYearSlider.MajorTickLabels = {'1959', '', '', '', '', '', '2068'};
            app.StartYearSlider.ValueChangedFcn = createCallbackFcn(app, @StartYearSliderValueChanged, true);
            app.StartYearSlider.Value = 1959;
            app.EndYearSliderLabel = uilabel(app.GameTab);
            app.EndYearSliderLabel.Text = 'End Year';
            app.EndYearSlider = uislider(app.GameTab);
            app.EndYearSlider.Limits = [1959 2068];
            app.EndYearSlider.MajorTicks = [1959 1977 1995 2013 2031 2049 2068];
            app.EndYearSlider.MajorTickLabels = {'1959', '', '', '', '', '', '2068'};
            app.EndYearSlider.ValueChangedFcn = createCallbackFcn(app, @EndYearSliderValueChanged, true);
            app.EndYearSlider.Value = 1959;
            app.ClicktoremoveLabel = uilabel(app.GameTab);
            app.ClicktoremoveLabel.Text = '(Click to remove)';
            app.ConstraintsTable = uitable(app.GameTab);
            app.ConstraintsTable.ColumnName = {'Sector'; 'Number Left'};
            app.ConstraintsTable.RowName = {};
            app.DeleteStrategyButton = uibutton(app.GameTab, 'push');
            app.DeleteStrategyButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteStrategyButtonPushed, true);
            app.DeleteStrategyButton.Text = 'Delete ';
            app.AddStrategyButton = uibutton(app.GameTab, 'push');
            app.AddStrategyButton.ButtonPushedFcn = createCallbackFcn(app, @AddStrategyButtonPushed, true);
            app.AddStrategyButton.Text = 'Add';
            app.UpdateGraphButton = uibutton(app.GameTab, 'push');
            app.UpdateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGraphButtonPushed, true);
            app.UpdateGraphButton.Text = 'Update Graph';
            app.ResetButton = uibutton(app.GameTab, 'push');
            app.ResetButton.Text = 'Reset';
            app.ConstraintsLabel = uilabel(app.GameTab);
            app.ConstraintsLabel.Text = 'Constraints';
            app.StrategyStartSpinnerLabel = uilabel(app.GameTab);
            app.StrategyStartSpinnerLabel.Text = 'Strategy Start';
            app.StrategyStartSpinner = uispinner(app.GameTab);
            app.StrategyStartSpinner.Limits = [2017 2068];
            app.StrategyStartSpinner.Value = 2017;
            app.GoalButtonGroup = uibuttongroup(app.GameTab);
            app.GoalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GoalButtonGroupSelectionChanged, true);
            app.GoalButtonGroup.Title = 'Goal';
            app.G2017Button = uiradiobutton(app.GoalButtonGroup);
            app.G2017Button.Text = 'Same as 2017';
            app.G2017Button.Value = true;
            app.G1980Button = uiradiobutton(app.GoalButtonGroup);
            app.G1980Button.Text = 'Same as 1980';
            app.G801980Button = uiradiobutton(app.GoalButtonGroup);
            app.G801980Button.Text = '80% of 1980';
            app.TotalWedgesNeededLabel = uilabel(app.GameTab);
            app.TotalWedgesNeededLabel.Text = 'Total Wedges Needed:';
            app.TotalWedgesTextArea = uitextarea(app.GameTab);
            app.TotalWedgesTextArea.Editable = 'off';
            app.ErrorTextArea = uitextarea(app.GameTab);
            app.ErrorTextArea.Editable = 'off';
            app.ErrorLogLabel = uilabel(app.GameTab);
            app.ErrorLogLabel.Text = 'Error Log';
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end